classdef LTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TimeSeriesGraph   matlab.ui.control.UIAxes
        PDFGraph          matlab.ui.control.UIAxes
        Title             matlab.ui.control.Label
        LoadncFileButton  matlab.ui.control.Button
        RunButton         matlab.ui.control.Button
        WeibullFit        matlab.ui.control.Label
        Slope             matlab.ui.control.Label
        Intercept         matlab.ui.control.Label
        SlopeValue        matlab.ui.control.Label
        InterceptValue    matlab.ui.control.Label
    end

    
    properties (Access = private)
        Input_File;
        Input_Path;
        data;
        time;
        elevations; 
    end
    
    methods (Access = private)
        
        function read_data(app)
            % Read Input File:
            app.data = ncread(append(app.Input_Path, app.Input_File), 'VAVH'); 
            
            % Assign Variables:
            app.elevations = app.data;
            app.elevations = app.elevations';
           
            
        end
        
        function plot_time_series(app)
            plot(app.TimeSeriesGraph, app.elevations);
            app.TimeSeriesGraph.XLim = [0 length(app.elevations)];
        end
        
        
        function plot_weibull(app)
            F = edf(app.elevations);
            x = F(:, 1);
            F1 = F(:, 2);
            plot(app.PDFGraph, log(x), log(-log1p(-F1)), 'b.', 'markersize', 8);
            
            U = [ones(size(x)) log(x)];
            b = U\log(-log1p(-F1));
            c = b(2);
            a = exp(-b(1)/c);
            
            mdl = fitlm(log(x), log(-log1p(-F1)));
            
            slope = mdl.Coefficients{2, 1};
            intercept = mdl.Coefficients{1, 1};
            
            app.SlopeValue.Text = num2str(round(slope, 2));
            app.InterceptValue.Text = num2str(round(intercept, 2));
            
            hold(app.PDFGraph, 'on');
            plot(app.PDFGraph, mdl)
            hold(app.PDFGraph, 'off');
            app.PDFGraph.XLabel.String = 'ln(h)';
            app.PDFGraph.YLabel.String = 'ln(-ln(1-F(h)))';
            app.PDFGraph.Title.String = 'Probability Plot';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadncFileButton
        function LoadncFileButtonPushed(app, event)
            [app.Input_File, app.Input_Path] = uigetfile('*.nc');
            app.RunButton.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.read_data();
            app.plot_time_series();
            app.plot_weibull();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSeriesGraph = uiaxes(app.UIFigure);
            title(app.TimeSeriesGraph, 'Variation of Height over Time')
            xlabel(app.TimeSeriesGraph, 'Time')
            ylabel(app.TimeSeriesGraph, 'Height')
            zlabel(app.TimeSeriesGraph, '')
            app.PDFGraph = uiaxes(app.UIFigure);
            title(app.PDFGraph, 'Probability Plot')
            xlabel(app.PDFGraph, '')
            ylabel(app.PDFGraph, '')
            zlabel(app.PDFGraph, '')
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Long Term Statistics';
            app.LoadncFileButton = uibutton(app.UIFigure, 'push');
            app.LoadncFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadncFileButtonPushed, true);
            app.LoadncFileButton.Text = 'Load .nc File';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'run.png';
            app.RunButton.Enable = 'off';
            app.RunButton.Text = '';
            app.WeibullFit = uilabel(app.UIFigure);
            app.WeibullFit.Text = 'Parameters for Weibull Fit';
            app.Slope = uilabel(app.UIFigure);
            app.Slope.Text = 'Slope:';
            app.Intercept = uilabel(app.UIFigure);
            app.Intercept.Text = 'y-intercept:';
            app.SlopeValue = uilabel(app.UIFigure);
            app.SlopeValue.Text = '';
            app.InterceptValue = uilabel(app.UIFigure);
            app.InterceptValue.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LTS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end