classdef WAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Spectrum                        matlab.ui.control.UIAxes
        TimeSeries                      matlab.ui.control.UIAxes
        Gauss                           matlab.ui.control.UIAxes
        Rayleigh                        matlab.ui.control.UIAxes
        FreqType                        matlab.ui.control.Switch
        SpectrumTable                   matlab.ui.control.Table
        RunButton                       matlab.ui.control.Button
        TimeSeriesTable                 matlab.ui.control.Table
        SpectralParameters              matlab.ui.control.Label
        TimeSeriesParameters            matlab.ui.control.Label
        LoadTimeSeriesFilecsvButton     matlab.ui.control.Button
        Title                           matlab.ui.control.Label
        MeanTimePeriodLabel             matlab.ui.control.Label
        MeanWaveHeightLabel             matlab.ui.control.Label
        RMSTimePeriodLabel              matlab.ui.control.Label
        RMSWaveHeightLabel              matlab.ui.control.Label
        SignificantWaveHeightLabel_2    matlab.ui.control.Label
        OneTenthHighestWaveHeightLabel  matlab.ui.control.Label
        PeakTimePeriodLabel             matlab.ui.control.Label
        SignificantWaveHeightLabel      matlab.ui.control.Label
        MeanTimePeriodLabel_2           matlab.ui.control.Label
        MeanPeakTimePeriodLabel         matlab.ui.control.Label
        MeanZeroCrossingTimePeriodLabel  matlab.ui.control.Label
        ShortTermStatsLabel             matlab.ui.control.Label
        GaussFit                        matlab.ui.control.Label
        RayleighFit                     matlab.ui.control.Label
        Mean                            matlab.ui.control.Label
        STD                             matlab.ui.control.Label
        MeanValue                       matlab.ui.control.Label
        STDValue                        matlab.ui.control.Label
        Sigma                           matlab.ui.control.Label
        SigmaValue                      matlab.ui.control.Label
        ExportUC                        matlab.ui.control.Button
        LongTermStatistics              matlab.ui.control.Button
        ResolutionKnob                  matlab.ui.control.DiscreteKnob
        Segments                        matlab.ui.control.Label
        ExportDC                        matlab.ui.control.Button
        WaveType                        matlab.ui.control.Switch
    end

    
    properties (Access = private)
        Input_File;
        Input_Path;
        data;
        time;
        elevations;
        frequencies;
        spectral_values;
        uheights;
        dheights;
    end
    
    methods (Access = private)
        
        function read_data(app)
            % Read Input File:
            app.data = csvread(append(app.Input_Path, app.Input_File)); 
            
            % Assign Variables:
            app.time = app.data(:,1);
            app.elevations = app.data(:,2);
            app.elevations = (app.elevations - mean(app.elevations))';
            app.time = app.time';
            
            % Clean Data:
            cutoff = [0.3 4]/(2*pi); %Cut-off frequencies
            dt = app.time(2)-app.time(1);
            fnyq = 1/(2*dt); 
            [b, a] = butter(4, cutoff/fnyq, 'bandpass'); %Get filter coefficients
            app.elevations = filtfilt(b, a, app.elevations);
            
        end
        
        function plot_time_series(app)
            plot(app.TimeSeries,app.time,app.elevations)
            tmax = length(app.time)*(app.time(2)-app.time(1));
            app.TimeSeries.XLim = [0 tmax];
        end
        
        function plot_spectrum(app)
            [app.frequencies, app.spectral_values] = periodogram(app.time, app.elevations, 32);
            plot(app.Spectrum,app.frequencies, app.spectral_values)
            [Amp,~] = max(app.spectral_values);
            app.Spectrum.XLim = [0 0.35];
            app.Spectrum.YLim = [0 1.1*Amp];
            app.Spectrum.YLabel.String = 'S(f) [m^2-s]';
            app.Spectrum.XLabel.String = ' Frequency [Hz]';
            app.FreqType.Enable = 'on';
            app.ResolutionKnob.Enable = 'on';
        end
        
        function change_Freq_Type(app, freq_type)
            if freq_type == 'w'
                w = (2*pi).*app.frequencies;
                Sw = app.spectral_values./(2*pi);
                
                plot(app.Spectrum, w, Sw)
                [Amp,~] = max(Sw);
                app.Spectrum.XLim = [0 2*pi*0.35];
                app.Spectrum.YLim = [0 1.1*Amp];
                app.Spectrum.YLabel.String = 'S(w) [m^2-s/rad]';
                app.Spectrum.XLabel.String = ' Frequency [rad/s]';
                
            elseif freq_type == 'f'
                plot(app.Spectrum,app.frequencies, app.spectral_values)
                [Amp,~] = max(app.spectral_values);
                app.Spectrum.XLim = [0 0.35];
                app.Spectrum.YLim = [0 1.1*Amp];
                app.Spectrum.YLabel.String = 'S(f) [m^2-s]';
                app.Spectrum.XLabel.String = ' Frequency [Hz]';
                
            end
 
        end
        
        function Change_Wave_Type(app, wave_type)
            if strcmp(wave_type, 'Down-Crossing')
                histogram(app.Rayleigh, app.dheights, 20, 'Normalization', 'pdf');
                pd = fitdist(app.dheights', 'Rayleigh');
                app.SigmaValue.Text = num2str(round(pd.B, 2));
                x_pdf = linspace(min(app.dheights), max(app.dheights));
                y_pdf = pdf(pd, x_pdf);
                line(app.Rayleigh, x_pdf, y_pdf, 'LineWidth', 2, 'Color', 'Red');
                app.Rayleigh.Title.String = "PDF of Down-Crossing Wave Heights";
            
            elseif strcmp(wave_type, 'Up-Crossing')
                u = app.uheights;
                histogram(app.Rayleigh, u, 20, 'Normalization', 'pdf');
                pd = fitdist(u', 'Rayleigh');
                app.SigmaValue.Text = num2str(round(pd.B, 2));
                x_pdf = linspace(min(u), max(u));
                y_pdf = pdf(pd, x_pdf);
                line(app.Rayleigh, x_pdf, y_pdf, 'LineWidth', 2, 'Color', 'Red');
                app.Rayleigh.Title.String = "PDF of Up-Crossing Wave Heights";              
                
            end
        end
        
        function change_Resolution(app, resolution)
            [app.frequencies, app.spectral_values] = spectrum(app.time, app.elevations, resolution);
            plot(app.Spectrum,app.frequencies, app.spectral_values);
            [Amp,~] = max(app.spectral_values);
            app.Spectrum.XLim = [0 0.35];
            app.Spectrum.YLim = [0 1.1*Amp];
        end
        
        function crossing_params(app)
           
            [Tm, Hm, Trms, Hrms, Hs, Ho, app.uheights] = ucp(app.time, app.elevations);
            [dTm, dHm, dTrms, dHrms, dHs, dHo, app.dheights] = dcp(app.time, app.elevations);
            
            UpCrossing =  round([Tm; Hm; Trms; Hrms; Hs; Ho], 2);
            DownCrossing = round([dTm; dHm; dTrms; dHrms; dHs; dHo], 2);
            
            
            app.TimeSeriesTable.Data = table(UpCrossing, DownCrossing);
            app.TimeSeriesTable.ColumnEditable = [false false];
            
        end
        
        function spectral_params(app)
            [Tp, Hs, Tm, Tpm, Tzm] = specp(app.frequencies, app.spectral_values);
            Spec = round([Tp; Hs; Tm; Tpm; Tzm], 2);
            app.SpectrumTable.Data = table(Spec);
            app.SpectrumTable.ColumnEditable = false;
        end
        
        function short_term_stats(app)
            histogram(app.Gauss, app.elevations, 'Normalization', 'pdf');
            pd = fitdist(app.elevations','Normal');
            app.MeanValue.Text = num2str(round(pd.mu, 2));
            app.STDValue.Text = num2str(round(pd.sigma, 2));
            x_pdf = linspace(min(app.elevations), max(app.elevations));
            y_pdf = pdf(pd, x_pdf);
            line(app.Gauss, x_pdf, y_pdf, 'LineWidth', 2, 'Color', 'Red');
            
            histogram(app.Rayleigh, app.uheights, 20, 'Normalization', 'pdf');
            pd = fitdist(app.uheights', 'Rayleigh');
            app.SigmaValue.Text = num2str(round(pd.B, 2));
            x_pdf = linspace(min(app.uheights), max(app.uheights));
            y_pdf = pdf(pd, x_pdf);
            line(app.Rayleigh, x_pdf, y_pdf, 'LineWidth', 2, 'Color', 'Red');
            app.WaveType.Enable = 'on';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTimeSeriesFilecsvButton
        function LoadTimeSeriesFilecsvButtonPushed(app, event)
            [app.Input_File, app.Input_Path] = uigetfile('*.csv');
            app.RunButton.Enable = 'on';
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.read_data();
            app.plot_time_series();
            app.plot_spectrum();
            app.crossing_params();
            app.spectral_params();
            app.short_term_stats();
            app.ExportUC.Enable = 'on';
            app.ExportDC.Enable = 'on';
        end

        % Value changed function: FreqType
        function FreqTypeValueChanged(app, event)
            value = app.FreqType.Value;
            app.change_Freq_Type(value);
        end

        % Value changed function: ResolutionKnob
        function ResolutionKnobValueChanged(app, event)
            value = str2double(app.ResolutionKnob.Value);
            app.change_Resolution(value);
            app.spectral_params();
        end

        % Button pushed function: LongTermStatistics
        function LongTermStatisticsButtonPushed(app, event)
            LTS
        end

        % Button pushed function: ExportUC
        function ExportUCButtonPushed(app, event)
            [periods, height] = ExpU(app.time, app.elevations);
            Results = zeros(length(periods), 2);    
            Results(:,2) = round(height, 3);
            Results(:,1) = round(periods, 3);
            writematrix(Results, 'Up-Crossing_Waves.csv');
            
            app.ExportUC.Text = 'Done!';
        end

        % Button pushed function: ExportDC
        function ExportDCButtonPushed(app, event)
            [periods, height] = ExpD(app.time, app.elevations);
            Results = zeros(length(periods), 2);    
            Results(:,2) = round(height, 2);
            Results(:,1) = round(periods, 3);
            writematrix(Results, 'Down-Crossing_Waves.csv');
            
            app.ExportDC.Text = 'Done!';
        end

        % Value changed function: WaveType
        function WaveTypeValueChanged(app, event)
            value = app.WaveType.Value;
            app.Change_Wave_Type(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Spectrum = uiaxes(app.UIFigure);
            title(app.Spectrum, 'Energy Spectrum')
            xlabel(app.Spectrum, 'X')
            ylabel(app.Spectrum, 'Y')
            zlabel(app.Spectrum, 'Z')
            app.TimeSeries = uiaxes(app.UIFigure);
            title(app.TimeSeries, 'Time Series')
            xlabel(app.TimeSeries, 'Time')
            ylabel(app.TimeSeries, 'Height')
            zlabel(app.TimeSeries, 'Z')
            app.Gauss = uiaxes(app.UIFigure);
            title(app.Gauss, 'PDF of Surface Deviations')
            xlabel(app.Gauss, 'Deviation from MWL (m)')
            ylabel(app.Gauss, 'Probability Density')
            zlabel(app.Gauss, 'Z')
            app.Rayleigh = uiaxes(app.UIFigure);
            title(app.Rayleigh, 'PDF of Up-Crossing Wave Heights')
            xlabel(app.Rayleigh, 'Height of Wave (m)')
            ylabel(app.Rayleigh, 'Probability Density')
            zlabel(app.Rayleigh, 'Z')
            app.FreqType = uiswitch(app.UIFigure, 'slider');
            app.FreqType.Items = {'f', 'w'};
            app.FreqType.ValueChangedFcn = createCallbackFcn(app, @FreqTypeValueChanged, true);
            app.FreqType.Enable = 'off';
            app.FreqType.Value = 'f';
            app.SpectrumTable = uitable(app.UIFigure);
            app.SpectrumTable.ColumnName = {'Value'};
            app.SpectrumTable.RowName = {};
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = 'run.png';
            app.RunButton.Enable = 'off';
            app.RunButton.Text = '';
            app.TimeSeriesTable = uitable(app.UIFigure);
            app.TimeSeriesTable.ColumnName = {'Up-Crossing'; 'Down-Crossing'};
            app.TimeSeriesTable.RowName = {};
            app.SpectralParameters = uilabel(app.UIFigure);
            app.SpectralParameters.Text = 'Spectral Parameters:';
            app.TimeSeriesParameters = uilabel(app.UIFigure);
            app.TimeSeriesParameters.Text = 'Time Series Parameters:';
            app.LoadTimeSeriesFilecsvButton = uibutton(app.UIFigure, 'push');
            app.LoadTimeSeriesFilecsvButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTimeSeriesFilecsvButtonPushed, true);
            app.LoadTimeSeriesFilecsvButton.Text = 'Load Time Series File (.csv)';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Wave Analysis Toolbox';
            app.MeanTimePeriodLabel = uilabel(app.UIFigure);
            app.MeanTimePeriodLabel.Text = 'Mean Time Period';
            app.MeanWaveHeightLabel = uilabel(app.UIFigure);
            app.MeanWaveHeightLabel.Text = 'Mean Wave Height';
            app.RMSTimePeriodLabel = uilabel(app.UIFigure);
            app.RMSTimePeriodLabel.Text = 'RMS Time Period';
            app.RMSWaveHeightLabel = uilabel(app.UIFigure);
            app.RMSWaveHeightLabel.Text = 'RMS Wave Height';
            app.SignificantWaveHeightLabel_2 = uilabel(app.UIFigure);
            app.SignificantWaveHeightLabel_2.Text = 'Significant Wave Height';
            app.OneTenthHighestWaveHeightLabel = uilabel(app.UIFigure);
            app.OneTenthHighestWaveHeightLabel.Text = {'One-Tenth Highest Wave Height'; ''};
            app.PeakTimePeriodLabel = uilabel(app.UIFigure);
            app.PeakTimePeriodLabel.Text = 'Peak Time Period';
            app.SignificantWaveHeightLabel = uilabel(app.UIFigure);
            app.SignificantWaveHeightLabel.Text = {'Significant Wave Height'; ''};
            app.MeanTimePeriodLabel_2 = uilabel(app.UIFigure);
            app.MeanTimePeriodLabel_2.Text = 'Mean Time Period';
            app.MeanPeakTimePeriodLabel = uilabel(app.UIFigure);
            app.MeanPeakTimePeriodLabel.Text = 'Mean Peak Time Period';
            app.MeanZeroCrossingTimePeriodLabel = uilabel(app.UIFigure);
            app.MeanZeroCrossingTimePeriodLabel.Text = 'Mean Zero-Crossing Time Period';
            app.ShortTermStatsLabel = uilabel(app.UIFigure);
            app.ShortTermStatsLabel.Text = 'Short Term Statistics';
            app.GaussFit = uilabel(app.UIFigure);
            app.GaussFit.Text = 'Parameters for Gaussian Fit';
            app.RayleighFit = uilabel(app.UIFigure);
            app.RayleighFit.Text = 'Parameters for Rayleigh Fit';
            app.Mean = uilabel(app.UIFigure);
            app.Mean.Text = 'Mean:';
            app.STD = uilabel(app.UIFigure);
            app.STD.Text = 'Standard Deviation:';
            app.MeanValue = uilabel(app.UIFigure);
            app.MeanValue.Text = '';
            app.STDValue = uilabel(app.UIFigure);
            app.STDValue.Text = '';
            app.Sigma = uilabel(app.UIFigure);
            app.Sigma.Text = 'Sigma:';
            app.SigmaValue = uilabel(app.UIFigure);
            app.SigmaValue.Text = '';
            app.ExportUC = uibutton(app.UIFigure, 'push');
            app.ExportUC.ButtonPushedFcn = createCallbackFcn(app, @ExportUCButtonPushed, true);
            app.ExportUC.Enable = 'off';
            app.ExportUC.Text = 'Export Up-Crossing Waves';
            app.LongTermStatistics = uibutton(app.UIFigure, 'push');
            app.LongTermStatistics.ButtonPushedFcn = createCallbackFcn(app, @LongTermStatisticsButtonPushed, true);
            app.LongTermStatistics.Text = 'Obtain Long Term Statistics';
            app.ResolutionKnob = uiknob(app.UIFigure, 'discrete');
            app.ResolutionKnob.Items = {'8', '16', '32', '64'};
            app.ResolutionKnob.ValueChangedFcn = createCallbackFcn(app, @ResolutionKnobValueChanged, true);
            app.ResolutionKnob.Enable = 'off';
            app.ResolutionKnob.Value = '32';
            app.Segments = uilabel(app.UIFigure);
            app.Segments.Text = 'No. of Segments';
            app.ExportDC = uibutton(app.UIFigure, 'push');
            app.ExportDC.ButtonPushedFcn = createCallbackFcn(app, @ExportDCButtonPushed, true);
            app.ExportDC.Enable = 'off';
            app.ExportDC.Text = 'Export Down-Crossing Waves';
            app.WaveType = uiswitch(app.UIFigure, 'slider');
            app.WaveType.Items = {'Up-Crossing', 'Down-Crossing'};
            app.WaveType.ValueChangedFcn = createCallbackFcn(app, @WaveTypeValueChanged, true);
            app.WaveType.Enable = 'off';
            app.WaveType.Value = 'Up-Crossing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WAT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end